classdef SpaderOpticsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ControltherestvariablesLabel  matlab.ui.control.Label
        CreatePlotLabel               matlab.ui.control.Label
        ReferenceButton               matlab.ui.control.Button
        MetadataLabel                 matlab.ui.control.Label
        SpaderOpticsLabel             matlab.ui.control.Label
        Variable3DropDown             matlab.ui.control.DropDown
        Variable3DropDownLabel        matlab.ui.control.Label
        Variable2DropDown             matlab.ui.control.DropDown
        Variable2DropDownLabel        matlab.ui.control.Label
        Variable1DropDown             matlab.ui.control.DropDown
        Variable1DropDownLabel        matlab.ui.control.Label
        OpticalXAxisDropDown          matlab.ui.control.DropDown
        XaxisDropDownLabel            matlab.ui.control.Label
        SolarCoverageRatioLabel       matlab.ui.control.Label
        UppertemperaturelimitKLabel   matlab.ui.control.Label
        ReferenceLabel                matlab.ui.control.Label
        QuantitytoPlotLabel           matlab.ui.control.Label
        OpticalQuantityDropDown       matlab.ui.control.DropDown
        AuthorDropDown                matlab.ui.control.DropDown
        AuthorLabel                   matlab.ui.control.Label
        MaterialDropDown              matlab.ui.control.DropDown
        MaterialLabel                 matlab.ui.control.Label
        OpticalAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SpaderOptics % Description
    end
    
    methods (Access = private)
        
        function updateOpticalPlot(app)

   
            % ==============================================================
            % 1. GET CURRENT MATERIAL, SOURCE AND SPREAD
            % ==============================================================
        
            materialIndex = find(strcmp( ...
                {app.SpaderOptics.material}, ...
                app.MaterialDropDown.Value), 1);
        
            if isempty(materialIndex)
                return
            end
        
            sources = app.SpaderOptics(materialIndex).dataset;
        
            sourceIndex = find(strcmp( ...
                {sources.author}, ...
                app.AuthorDropDown.Value), 1);
        
            if isempty(sourceIndex)
                return
            end

            src = sources(sourceIndex);


            % ==============================================================
            % 2. GET CURRENT SELECTIONS
            % ==============================================================
        
            quantity = app.OpticalQuantityDropDown.Value;
            xAxis    = app.OpticalXAxisDropDown.Value;
        
            isTdepend = endsWith(app.AuthorDropDown.Value, '-Tdepend');
        
        
            % ==============================================================
            % 3. GET VARIABLE INDICES
            % ==============================================================
        
            variable1 = app.Variable1DropDown.Value;
            variable2 = app.Variable2DropDown.Value;
            variable3 = app.Variable3DropDown.Value;
        
        
            % Convert dropdown values to indices.
            % The dropdown values are numerical strings, so we find the
            % closest corresponding value in the database arrays.
        
            index1 = [];
            index2 = [];
            index3 = [];
        
        
            if ~strcmp(variable1, '-')
        
                value1 = str2double(variable1);
        
                if ~isnan(value1)
        
                    switch app.Variable1DropDownLabel.Text
        
                        case 'Incident angle (deg):'
        
                            [~, index1] = min(abs( ...
                                src.calculation_incident_angles_deg - value1));

                        case 'Emission angle (deg):'
        
                            [~, index1] = min(abs( ...
                                src.calculation_incident_angles_deg - value1));
                        
                        case 'Angle (deg):'
        
                            [~, index1] = min(abs( ...
                                src.calculation_incident_angles_deg - value1));
        
                        case 'Thickness (um):'
        
                            [~, index1] = min(abs( ...
                                src.calculation_thickness_um - value1));
        
                        case 'Temperature (K):'
        
                            [~, index1] = min(abs( ...
                                src.calculation_temperature_K - value1));
        
                        case 'Source temperature (K):'
        
                            [~, index1] = min(abs( ...
                                src.original_temperature_K - value1));
        
                        case 'Wavelength (um):'
        
                            [~, index1] = min(abs( ...
                                src.resampled_wavelength_um - value1));
        
                        case 'Solar concentration multiplier:'
        
                            [~, index1] = min(abs( ...
                                src.calculation_solar_concentration_multipliers - value1));
        
                    end
        
                end
        
            end
        
        
            if ~strcmp(variable2, '-')
        
                value2 = str2double(variable2);
        
                if ~isnan(value2)
        
                    switch app.Variable2DropDownLabel.Text
        
                        case 'Incident angle (deg):'
        
                            [~, index2] = min(abs( ...
                                src.calculation_incident_angles_deg - value2));

                        case 'Emission angle (deg):'
        
                            [~, index2] = min(abs( ...
                                src.calculation_incident_angles_deg - value2));

                        case 'Angle (deg):'
        
                            [~, index2] = min(abs( ...
                                src.calculation_incident_angles_deg - value2));
        
                        case 'Thickness (um):'
        
                            [~, index2] = min(abs( ...
                                src.calculation_thickness_um - value2));
        
                        case 'Temperature (K):'
        
                            [~, index2] = min(abs( ...
                                src.calculation_temperature_K - value2));
        
                        case 'Wavelength (um):'
        
                            [~, index2] = min(abs( ...
                                src.resampled_wavelength_um - value2));
        
                        case 'Solar concentration multiplier:'
        
                            [~, index2] = min(abs( ...
                                src.calculation_solar_concentration_multipliers - value2));
        
                    end
        
                end
        
            end
        
        
            if ~strcmp(variable3, '-')
        
                value3 = str2double(variable3);
        
                if ~isnan(value3)
        
                    switch app.Variable3DropDownLabel.Text
        
                        case 'Incident angle:'
        
                            [~, index3] = min(abs( ...
                                src.calculation_incident_angles_deg - value3));

                        case 'Emission angle (deg):'
        
                            [~, index3] = min(abs( ...
                                src.calculation_incident_angles_deg - value3));
                           
                        case 'Angle (deg):'
        
                            [~, index3] = min(abs( ...
                                src.calculation_incident_angles_deg - value3));
        
                        case 'Thickness (um):'
        
                            [~, index3] = min(abs( ...
                                src.calculation_thickness_um - value3));
        
                        case 'Temperature (K):'
        
                            [~, index3] = min(abs( ...
                                src.calculation_temperature_K - value3));

                        case 'Wavelength (um):'
        
                            [~, index3] = min(abs( ...
                                src.resampled_wavelength_um - value3));
        
                        case 'Solar concentration multiplier:'
        
                            [~, index3] = min(abs( ...
                                src.calculation_solar_concentration_multipliers - value3));


                    end
        
                end
        
            end
        
        
            % ==============================================================
            % 4. CLEAR AXES
            % ==============================================================
        
            cla(app.OpticalAxes);

            legend(app.OpticalAxes, 'off');
            title(app.OpticalAxes, '');
        
            hold(app.OpticalAxes, 'on');

            % ==============================================================
            % 4b. SOURCE SPREAD (hemispherical emissivity)
            % ==============================================================

            if ismember(quantity, ...
                    {'Hemispherical Emissivity — Source Spread', ...
                     'Hemispherical Emissivity — Relative Source Spread'})

                % Spread data stored by Step5 for this material
                spread = struct();
                if isfield(app.SpaderOptics, 'source_spread')
                    spread = app.SpaderOptics(materialIndex).source_spread;
                end

                if ~isfield(spread, 'min') || isempty(spread.min)
                    title(app.OpticalAxes, 'Spread not available for this material');
                    hold(app.OpticalAxes, 'off');
                    return
                end

                v = str2double(variable1);
                if isnan(v) || isempty(index1)
                    return
                end

                switch xAxis

                    case 'Temperature'

                        x = spread.temperature_K(:);
                        [~, k] = min(abs(spread.thickness_um(:) - v));

                        lo  = spread.min(:, k);
                        hi  = spread.max(:, k);
                        mean_v = spread.mean(:, k);
                        med = spread.median(:, k);
                        rel = spread.range_rel_pct(:, k);

                        % selected author (own grid)
                        xs = src.calculation_temperature_K(:);
                        ys = src.calculated_E_hemispherical(:, index1);

                        xlabel(app.OpticalAxes, 'Temperature (K)');

                    case 'Thickness'

                        x = spread.thickness_um(:);
                        [~, k] = min(abs(spread.temperature_K(:) - v));

                        lo  = spread.min(k, :).';
                        hi  = spread.max(k, :).';
                        mean_v = spread.mean(k, :).';
                        med = spread.median(k, :).';
                        rel = spread.range_rel_pct(k, :).';

                        xs = src.calculation_thickness_um(:);
                        ys = src.calculated_E_hemispherical(index1, :).';

                        xlabel(app.OpticalAxes, 'Thickness (\mum)');

                    otherwise
                        return

                end

                if strcmp(quantity, 'Hemispherical Emissivity — Source Spread')

                    ok = isfinite(lo) & isfinite(hi);

                    hBand = fill(app.OpticalAxes, ...
                        [x(ok); flipud(x(ok))], ...
                        [lo(ok); flipud(hi(ok))], ...
                        [0.2 0.4 0.8], ...
                        'FaceAlpha', 0.25, 'EdgeColor', 'none');

                    hMed = plot(app.OpticalAxes, x, med, '--', ...
                        'Color', [0.2 0.4 0.8], 'LineWidth', 1.5);

                    hAvg = plot(app.OpticalAxes, x, mean_v, ':', ...
                        'Color', [0.85 0.33 0.1], 'LineWidth', 1.8);

                    hSel = plot(app.OpticalAxes, xs, ys, 'k-', ...
                        'LineWidth', 1.5);

                    legend(app.OpticalAxes, [hBand hMed hAvg hSel], ...
                        {sprintf('Min–max (%d sources)', numel(spread.authors)), ...
                         'Median', ...
                         'Mean', ...
                         ['Selected: ' app.AuthorDropDown.Value]}, ...
                        'Location', 'best', 'Box', 'off');

                    ylabel(app.OpticalAxes, 'Hemispherical Emissivity');

                else

                    plot(app.OpticalAxes, x, rel, 'LineWidth', 1.5);

                    ylabel(app.OpticalAxes, 'Relative range (% of median)');

                end

                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');

                return
            end
        
        
            % ==============================================================
            % 5. REFRACTIVE INDEX / EXTINCTION COEFFICIENT
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Refractive index (n)', ...
                     'Extinction coefficient (k)'})
        
                wavelength = src.resampled_wavelength_um;
        
                if strcmp(quantity, 'Refractive index (n)')
                    opticalData = real(src.resampled_n_complex);
                    quantityLabel = 'Refractive index (n)';
                else
                    opticalData = imag(src.resampled_n_complex);
                    quantityLabel = 'Extinction coefficient (k)';
                end
        
        
                if isTdepend
        
                    if isempty(index1)
                        return
                    end
        
                    y = opticalData(:, index1);
        
                else
        
                    y = opticalData;
        
                end
        
        
                plot(app.OpticalAxes, wavelength, y);
        
                xlabel(app.OpticalAxes, 'Wavelength (\mum)');
                ylabel(app.OpticalAxes, quantityLabel);
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end

            % ==============================================================
            % 5. THERMAL CONDUCTIVITY
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Thermal Conductivity'})
        
                temperature = src.thermal_conductivity_temperature_grid_K;

                opticalData = src.thermal_conductivity_W_mK;
                quantityLabel = 'Thermal Conductivity (W/m/K)';

                plot(app.OpticalAxes, temperature, opticalData);
        
                xlabel(app.OpticalAxes, 'Temperature (K)');
                ylabel(app.OpticalAxes, quantityLabel);
                %title(app.OpticalAxes, quantityLabel);
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 6. DIRECTIONAL REFLECTIVITY / TRANSMISSIVITY / ABSORPTIVITY
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Directional Reflectivity', ...
                     'Directional Transmissivity', ...
                     'Directional Absorptivity'})
        
                switch quantity
        
                    case 'Directional Reflectivity'
                        opticalData = src.calculated_R_directional;
                        quantityLabel = 'Directional Reflectivity';
        
                    case 'Directional Transmissivity'
                        opticalData = src.calculated_T_directional;
                        quantityLabel = 'Directional Transmissivity';
        
                    case 'Directional Absorptivity'
                        opticalData = src.calculated_A_directional;
                        quantityLabel = 'Directional Absorptivity';
        
                end
        
        
                switch xAxis
        
                    case 'Wavelength'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        wavelength = src.resampled_wavelength_um;
        
                        if isTdepend
        
                            if isempty(index3)
                                return
                            end
        
                            y = squeeze(opticalData(:, index1, index2, index3));
        
                        else
        
                            y = squeeze(opticalData(:, index1, index2));
        
                        end
        
                        xlabel(app.OpticalAxes, 'Wavelength (\mum)');
        
        
                    case 'Incident angle'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        angles = src.calculation_incident_angles_deg;
        
                        if isTdepend
        
                            if isempty(index3)
                                return
                            end
        
                            y = squeeze(opticalData(index1, :, index2, index3));
        
                        else
        
                            y = squeeze(opticalData(index1, :, index2));
        
                        end
        
                        xlabel(app.OpticalAxes, 'Incident angle (deg)');
        
        
                    case 'Thickness'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        thickness = src.calculation_thickness_um;
        
                        if isTdepend
        
                            if isempty(index3)
                                return
                            end
        
                            y = squeeze(opticalData(index1, index2, :, index3));
        
                        else
        
                            y = squeeze(opticalData(index1, index2, :));
        
                        end
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    case 'Temperature'
        
                        if ~isTdepend
                            return
                        end
        
                        if isempty(index1) || isempty(index2) || isempty(index3)
                            return
                        end
        
                        temperature = src.calculation_temperature_K;
        
                        y = squeeze(opticalData(index1, index2, index3, :));
        
                        xlabel(app.OpticalAxes, 'Temperature (K)');
        
                    otherwise
        
                        return
        
                end
        
        
                if strcmp(xAxis, 'Wavelength')
                    x = wavelength;
                elseif strcmp(xAxis, 'Incident angle')
                    x = angles;
                elseif strcmp(xAxis, 'Thickness')
                    x = thickness;
                else
                    x = temperature;
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, quantityLabel);
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 7. HEMISPHERICAL REFLECTIVITY / TRANSMISSIVITY / ABSORPTIVITY
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Hemispherical Reflectivity', ...
                     'Hemispherical Transmissivity', ...
                     'Hemispherical Absorptivity'})
        
                switch quantity
        
                    case 'Hemispherical Reflectivity'
                        opticalData = src.calculated_R_hemispherical;
                        quantityLabel = 'Hemispherical Reflectivity';
        
                    case 'Hemispherical Transmissivity'
                        opticalData = src.calculated_T_hemispherical;
                        quantityLabel = 'Hemispherical Transmissivity';
        
                    case 'Hemispherical Absorptivity'
                        opticalData = src.calculated_A_hemispherical;
                        quantityLabel = 'Hemispherical Absorptivity';
        
                end
        
        
                switch xAxis
        
                    case 'Wavelength'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.resampled_wavelength_um;
        
                        if isTdepend
        
                            if isempty(index2)
                                return
                            end
        
                            y = squeeze(opticalData(:, index1, index2));
        
                        else
        
                            y = squeeze(opticalData(:, index1));
        
                        end
        
                        xlabel(app.OpticalAxes, 'Wavelength (\mum)');
        
        
                    case 'Thickness'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        if isTdepend
        
                            if isempty(index2)
                                return
                            end
        
                            y = squeeze(opticalData(index1, :, index2));
        
                        else
        
                            y = squeeze(opticalData(index1, :));
        
                        end
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    case 'Temperature'
        
                        if ~isTdepend
                            return
                        end
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_temperature_K;
        
                        y = squeeze(opticalData(index1, index2, :));
        
                        xlabel(app.OpticalAxes, 'Temperature (K)');
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, quantityLabel);
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 8. DIRECTIONAL EMISSIVITY
            % ==============================================================
        
            if strcmp(quantity, 'Directional Emissivity')
        
                opticalData = src.calculated_E_directional;
        
                switch xAxis
        
                    case 'Temperature'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_temperature_K;
        
                        y = squeeze(opticalData(:, index1, index2));
        
                        xlabel(app.OpticalAxes, 'Temperature (K)');
        
        
                    case 'Emission angle'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_incident_angles_deg;
        
                        y = squeeze(opticalData(index1, :, index2));
        
                        xlabel(app.OpticalAxes, 'Emission angle (deg)');
        
        
                    case 'Thickness'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        y = squeeze(opticalData(index1, index2, :));
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, 'Directional Emissivity');
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 9. HEMISPHERICAL EMISSIVITY
            % ==============================================================
        
            if strcmp(quantity, 'Hemispherical Emissivity')
        
                opticalData = src.calculated_E_hemispherical;
        
                switch xAxis
        
                    case 'Temperature'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_temperature_K;
        
                        y = squeeze(opticalData(:, index1));
        
                        xlabel(app.OpticalAxes, 'Temperature (K)');
        
        
                    case 'Thickness'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        y = squeeze(opticalData(index1, :));
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, 'Hemispherical Emissivity');
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 10. DIRECTIONAL FLUXES
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Directional Reflected Flux', ...
                     'Directional Transmitted Flux', ...
                     'Directional Absorbed Flux'})
        
                switch quantity
        
                    case 'Directional Reflected Flux'
                        opticalData = src.calculated_directional_Reflected_flux_W_m2;
                        quantityLabel = 'Directional reflected solar flux (W/m^2)';
        
                    case 'Directional Transmitted Flux'
                        opticalData = src.calculated_directional_Transmitted_flux_W_m2;
                        quantityLabel = 'Directional transmitted solar flux (W/m^2)';
        
                    case 'Directional Absorbed Flux'
                        opticalData = src.calculated_directional_Absorbed_flux_W_m2;
                        quantityLabel = 'Directional absorbed solar flux (W/m^2)';
        
                end
        
        
                switch xAxis
        
                    case 'Incident angle'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_incident_angles_deg;
        
                        if isTdepend
        
                            if isempty(index2)
                                return
                            end
        
                            y = squeeze(opticalData(index2, :, index1));
        
                        else
        
                            y = squeeze(opticalData(:, index1));
        
                        end
        
                        xlabel(app.OpticalAxes, 'Incident angle (deg)');
        
        
                    case 'Thickness'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        if isTdepend
        
                            if isempty(index2)
                                return
                            end
        
                            y = squeeze(opticalData(index2, index1, :));
        
                        else
        
                            y = squeeze(opticalData(index1, :));
        
                        end
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    case 'Temperature'
        
                        if ~isTdepend
                            return
                        end
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_temperature_K;
        
                        y = squeeze(opticalData(:, index1, index2));
        
                        xlabel(app.OpticalAxes, 'Temperature (K)');
        
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, quantityLabel);
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
 
            % ==============================================================
            % 12. HEMISPHERICAL FLUXES
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Hemispherical Reflected Flux', ...
                     'Hemispherical Transmitted Flux', ...
                     'Hemispherical Absorbed Flux'})
        
                switch quantity
        
                    case 'Hemispherical Reflected Flux'
                        opticalData = src.calculated_hemispherical_Reflected_flux_W_m2;
                        quantityLabel = 'Hemispherical reflected solar flux (W/m^2)';
        
                    case 'Hemispherical Transmitted Flux'
                        opticalData = src.calculated_hemispherical_Transmitted_flux_W_m2;
                        quantityLabel = 'Hemispherical transmitted solar flux (W/m^2)';
        
                    case 'Hemispherical Absorbed Flux'
                        opticalData = src.calculated_hemispherical_Absorbed_flux_W_m2;
                        quantityLabel = 'Hemispherical absorbed solar flux (W/m^2)';
        
                end
        
        
                switch xAxis
        
                    case 'Thickness'
        
                        x = src.calculation_thickness_um;
        
                        if isTdepend
        
                            if isempty(index1)
                                return
                            end
        
                            y = squeeze(opticalData(index1, :));
                            
        
                        else
        
                            y = opticalData(:);
        
                        end
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    case 'Temperature'
        
                        if ~isTdepend
                            return
                        end
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_temperature_K;
        
                        y = squeeze(opticalData(:, index1));
        
                        xlabel(app.OpticalAxes, 'Temperature (K)');
        
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, quantityLabel);
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 13. HEMISPHERICAL EMITTED FLUX
            % ==============================================================
        
            if strcmp(quantity, 'Hemispherical Emitted Flux')
        
                opticalData = src.calculated_hemispherical_Emitted_flux_W_m2;
        
                switch xAxis
        
                    case 'Temperature'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_temperature_K;
        
                        y = squeeze(opticalData(:, index1));
        
                        xlabel(app.OpticalAxes, 'Temperature (K)');
        
        
                    case 'Thickness'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        y = squeeze(opticalData(index1, :));
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, ...
                    'Hemispherical emitted flux (W/m^2)');
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 14. LUMPED BODY EQUILIBRIUM TEMPERATURE (dir-hemi)
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Lumped Body Equilibrium Temperature — Directional A / Hemispherical E'})
 
               opticalData = src.calculated_lumped_equilibrium_temperature_dirA_hemiE_K;

                switch xAxis
        
                    case 'Incident angle'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_incident_angles_deg;
        
                        y = squeeze(opticalData(:, index1, index2));
        
                        xlabel(app.OpticalAxes, 'Incident angle (deg)');
        
        
                    case 'Thickness'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        y = squeeze(opticalData(index1, :, index2));
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    case 'Solar concentration multiplier'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_solar_concentration_multipliers;
        
                        y = squeeze(opticalData(index1, index2, :));
        
                        xlabel(app.OpticalAxes, ...
                            'Solar concentration multiplier');
        
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, 'Lumped Body Equilibrium Temperature (K)');
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end


            % ==============================================================
            % 15. LUMPED BODY EQUILIBRIUM TEMPERATURE (hemi-hemi)
            % ==============================================================
        
            if strcmp(quantity, 'Lumped Body Equilibrium Temperature — Hemispherical A / Hemispherical E')
             
                opticalData = src.calculated_lumped_equilibrium_temperature_hemiA_hemiE_K;
        
        
                switch xAxis
             
                    case 'Thickness'
        
                        if isempty(index1)
                          
                            return
                        end
        
                        x = src.calculation_thickness_um;
                       
        
                        y = squeeze(opticalData(1,:, index1));
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');
        
        
                    case 'Solar concentration multiplier'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_solar_concentration_multipliers;
        
                        y = squeeze(opticalData(1, index1, :));
        
                        xlabel(app.OpticalAxes, ...
                            'Solar concentration multiplier');
        
        
                    otherwise
        
                        return
        
                end
        
                plot(app.OpticalAxes, x, y);
        
                ylabel(app.OpticalAxes, 'Lumped Body Equilibrium Temperature (K)');
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end

            % ==============================================================
            % DIRECTIONAL ABSORPTIVITY / HEMISPHERICAL EMISSIVITY
            % - CONDUCTIVE FLUX
            % - TEMPERATURE AT THE FRONT SIDE OF THE SLAB
            % - TEMPERATURE AT THE REAR SIDE OF THE SLAB
            % - TEMPERATURE AT THE MIDDLE OF THE SLAB
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Conductive Flux — Directional A / Hemispherical E', ...
                    'Temperature at the front side of the slab — Directional A / Hemispherical E', ...
                    'Temperature at the rear side of the slab — Directional A / Hemispherical E', ...
                    'Temperature at the middle of the slab — Directional A / Hemispherical E'})
        

                switch quantity
        
                    case 'Conductive Flux — Directional A / Hemispherical E'
                        opticalData = ...
                            src.calculated_Conductive_flux_dirA_hemiE_W_m2;
        
                    case 'Temperature at the front side of the slab — Directional A / Hemispherical E'
                        opticalData = ...
                            src.calculated_slab_equilibrium_temperature_front_dirA_hemiE_K;
        
                    case 'Temperature at the rear side of the slab — Directional A / Hemispherical E'
                        opticalData = ...
                            src.calculated_slab_equilibrium_temperature_rear_dirA_hemiE_K;

                    case 'Temperature at the middle of the slab — Directional A / Hemispherical E'
                        opticalData = ...
                            src.calculated_slab_equilibrium_temperature_mid_dirA_hemiE_K;
        
        
                end
        
        
                switch xAxis
        
                    case 'Incident angle'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_incident_angles_deg;
        
                        y = squeeze(opticalData(:, index1, index2));
        
                        xlabel(app.OpticalAxes, 'Incident angle (deg)');

                    case 'Thickness'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        y = squeeze(opticalData(index1, :, index2));
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');

                    case 'Solar concentration multiplier'
        
                        if isempty(index1) || isempty(index2)
                            return
                        end
        
                        x = src.calculation_solar_concentration_multipliers;
        
                        y = squeeze(opticalData(index1, index2, :));
        
                        xlabel(app.OpticalAxes, ...
                            'Solar concentration multiplier');
        
        
                    otherwise
        
                        return
        
                end

                plot(app.OpticalAxes, x, y);
        
                switch quantity
        
                    case 'Conductive Flux — Directional A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Conductive Flux (W/m/K)');
        
                    case 'Temperature at the front side of the slab — Directional A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Temperature at the front side of the slab (K)');

                    case 'Temperature at the rear side of the slab — Directional A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Temperature at the rear side of the slab (K)');

                    case 'Temperature at the middle of the slab — Directional A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Temperature at the middle of the slab (K)');
        
        
                 end

                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
   
            % ==============================================================
            % HEMISPHERICAL ABSORPTIVITY / HEMISPHERICAL EMISSIVITY
            % - CONDUCTIVE FLUX
            % - TEMPERATURE AT THE FRONT SIDE OF THE SLAB
            % - TEMPERATURE AT THE REAR SIDE OF THE SLAB
            % - TEMPERATURE AT THE MIDDLE OF THE SLAB
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Conductive Flux — Hemispherical A / Hemispherical E', ...
                    'Temperature at the front side of the slab — Hemispherical A / Hemispherical E', ...
                    'Temperature at the rear side of the slab — Hemispherical A / Hemispherical E', ...
                    'Temperature at the middle of the slab — Hemispherical A / Hemispherical E'})
        

                  switch quantity
        
                     case 'Conductive Flux — Hemispherical A / Hemispherical E'
                        opticalData = ...
                            src.calculated_Conductive_flux_hemiA_hemiE_W_m2;
        
                    case 'Temperature at the front side of the slab — Hemispherical A / Hemispherical E'
                        opticalData = ...
                            src.calculated_slab_equilibrium_temperature_front_hemiA_hemiE_K;

                    case 'Temperature at the rear side of the slab — Hemispherical A / Hemispherical E'
                        opticalData = ...
                            src.calculated_slab_equilibrium_temperature_rear_hemiA_hemiE_K;

                    case 'Temperature at the middle of the slab — Hemispherical A / Hemispherical E'
                        opticalData = ...
                            src.calculated_slab_equilibrium_temperature_mid_hemiA_hemiE_K;
        
        
                 end

                  switch xAxis
        
                      case 'Thickness'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_thickness_um;
        
                        y = squeeze(opticalData(1, :, index1));
        
                        xlabel(app.OpticalAxes, 'Thickness (\mum)');

                      case 'Solar concentration multiplier'
        
                        if isempty(index1)
                            return
                        end
        
                        x = src.calculation_solar_concentration_multipliers;
        
                        y = squeeze(opticalData(1, index1, :));
        
                        xlabel(app.OpticalAxes, ...
                            'Solar concentration multiplier');
        
        
                    otherwise
        
                        return
        
                  end

                plot(app.OpticalAxes, x, y);

                switch quantity
        
                      case 'Conductive Flux — Hemispherical A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Conductive Flux (W/m/K)');
        
                      case 'Temperature at the front side of the slab — Hemispherical A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Temperature at the front side of the slab (K)');

                      case 'Temperature at the rear side of the slab — Hemispherical A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Temperature at the rear side of the slab (K)');

                      case 'Temperature at the middle of the slab — Hemispherical A / Hemispherical E'
                        ylabel(app.OpticalAxes, 'Temperature at the middle of the slab (K)');
        
        
                 end
       
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end
        
        
            % ==============================================================
            % 16. THERMAL COVERAGE RATIO
            % ==============================================================
        
            if strcmp(quantity, 'Thermal Coverage Ratio')
        
                if ~strcmp(xAxis, 'Temperature')
                    return
                end
        
                x = src.calculation_temperature_K;
                y = src.metrics_thermal_coverage_ratio;
        
                plot(app.OpticalAxes, x, y);
        
                xlabel(app.OpticalAxes, 'Temperature (K)');
                ylabel(app.OpticalAxes, 'Thermal Coverage Ratio');
        
                grid(app.OpticalAxes, 'on');
                hold(app.OpticalAxes, 'off');
        
                return
            end

        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = load('SpaderOptics.mat', 'SpaderOptics');
            app.SpaderOptics = data.SpaderOptics;
            app.MaterialDropDown.Items = {app.SpaderOptics.material};
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            value = app.MaterialDropDown.Value;
            
            materialIndex = find(strcmp( ...
            {app.SpaderOptics.material}, ...
            app.MaterialDropDown.Value), 1);
        
            if isempty(materialIndex)
                return
            end
            
            sources = app.SpaderOptics(materialIndex).dataset;
            
            app.AuthorDropDown.Items = {sources.author};
            
            if ~isempty(app.AuthorDropDown.Items)
                app.AuthorDropDown.Value = app.AuthorDropDown.Items{1};
            
                AuthorDropDownValueChanged(app, []);
            end
            
        end

        % Value changed function: AuthorDropDown
        function AuthorDropDownValueChanged(app, event)
            value = app.AuthorDropDown.Value;
            materialIndex = find(strcmp( ...
                {app.SpaderOptics.material}, ...
                app.MaterialDropDown.Value), 1);
            
            if isempty(materialIndex)
                return
            end
            
            sources = app.SpaderOptics(materialIndex).dataset;
            
            sourceIndex = find(strcmp( ...
                {sources.author}, ...
                app.AuthorDropDown.Value), 1);
            
            if isempty(sourceIndex)
                return
            end
            
            src = sources(sourceIndex);
            
            app.UppertemperaturelimitKLabel.Text = ...
                "Upper temperature limit (K): " + string(src.upper_temperature_limit_K);
            app.SolarCoverageRatioLabel.Text = ...
                "Solar coverage ratio: " + string(src.metrics_solar_coverage_ratio);

            plot(app.OpticalAxes, ...
                src.resampled_wavelength_um, ...
                src.calculated_R_directional(:,1,1));
            
            xlabel(app.OpticalAxes, 'Wavelength (\mum)');
            ylabel(app.OpticalAxes, 'Reflectance');
            grid(app.OpticalAxes, 'on');

            OpticalQuantityDropDownValueChanged(app, []);
        end

        % Button pushed function: ReferenceButton
        function ReferenceButtonPushed(app, event)
           
    materialIndex = find(strcmp( ...
        {app.SpaderOptics.material}, ...
        app.MaterialDropDown.Value), 1);

    if isempty(materialIndex)
        return
    end

    sources = app.SpaderOptics(materialIndex).dataset;

    sourceIndex = find(strcmp( ...
        {sources.author}, ...
        app.AuthorDropDown.Value), 1);

    if isempty(sourceIndex)
        return
    end

    src = sources(sourceIndex);

    referenceFigure = uifigure( ...
        'Name', 'Reference', ...
        'Position', [300 200 700 400]);

    referenceText = uitextarea(referenceFigure, ...
        'Position', [20 60 660 320], ...
        'Value', {...
            'Refractive Index Data:'; ...
            char(src.reference); ...
            ''; ...
            'Thermal Conductivity Data:'; ...
            char(src.thermal_conductivity_reference) ...
         }, ...
        'Editable', 'off');

    uibutton(referenceFigure, ...
        'push', ...
        'Text', 'Close', ...
        'Position', [600 20 80 25], ...
        'ButtonPushedFcn', @(btn,event) close(referenceFigure));

        end

        % Value changed function: OpticalXAxisDropDown
        function OpticalXAxisDropDownValueChanged(app, event)

            quantity = app.OpticalQuantityDropDown.Value;
            xAxis    = app.OpticalXAxisDropDown.Value;
        
        
            % ==============================================================
            % GET CURRENT MATERIAL AND SOURCE
            % ==============================================================
        
            materialIndex = find(strcmp( ...
                {app.SpaderOptics.material}, ...
                app.MaterialDropDown.Value), 1);
        
            if isempty(materialIndex)
                return
            end
        
            sources = app.SpaderOptics(materialIndex).dataset;
        
            sourceIndex = find(strcmp( ...
                {sources.author}, ...
                app.AuthorDropDown.Value), 1);
        
            if isempty(sourceIndex)
                return
            end
        
            src = sources(sourceIndex);
        
            isTdepend = endsWith(app.AuthorDropDown.Value, '-Tdepend');
        
        
            % ==============================================================
            % DEFAULT STATE
            % ==============================================================
        
            app.Variable1DropDownLabel.Text = 'Variable:';
            app.Variable2DropDownLabel.Text = 'Variable:';
            app.Variable3DropDownLabel.Text = 'Variable:';
        
            app.Variable1DropDown.Items = {'-'};
            app.Variable2DropDown.Items = {'-'};
            app.Variable3DropDown.Items = {'-'};
        
            app.Variable1DropDown.Value = '-';
            app.Variable2DropDown.Value = '-';
            app.Variable3DropDown.Value = '-';
        
        
            % ==============================================================
            % REFRACTIVE INDEX / EXTINCTION COEFFICIENT
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Refractive index (n)', ...
                     'Extinction coefficient (k)'})
        
                if isTdepend
        
                    app.Variable1DropDownLabel.Text = ...
                        'Source temperature (K):';
        
                    app.Variable1DropDown.Items = ...
                        string(src.original_temperature_K);
        
                    app.Variable1DropDown.Value = ...
                        app.Variable1DropDown.Items{1};
        
                end
        
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % THERMAL CONDUCTIVITY
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Thermal Conductivity'})

                updateOpticalPlot(app);
                return
            end
        
        
            % ==============================================================
            % DIRECTIONAL REFLECTIVITY / TRANSMISSIVITY / ABSORPTIVITY
            % ==============================================================
        
            if ismember(quantity, ...
                    {'Directional Reflectivity', ...
                     'Directional Transmissivity', ...
                     'Directional Absorptivity'})
        
                switch xAxis
        
        
                    % ------------------------------------------------------
                    % X = WAVELENGTH
                    % ------------------------------------------------------
        
                    case 'Wavelength'
        
                        app.Variable1DropDownLabel.Text = ...
                            'Incident angle (deg):';
        
                        app.Variable2DropDownLabel.Text = ...
                            'Thickness (um):';
        
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_incident_angles_deg);
        
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_thickness_um);
        
        
                        % Tdepend has temperature as Variable 3
                        if isTdepend
        
                            app.Variable3DropDownLabel.Text = ...
                                'Temperature (K):';
        
                            app.Variable3DropDown.Items = ...
                                string(src.calculation_temperature_K);
        
                        end
        
        
                    % ------------------------------------------------------
                    % X = INCIDENT ANGLE
                    % ------------------------------------------------------
        
                    case 'Incident angle'
        
                        app.Variable1DropDownLabel.Text = ...
                            'Wavelength (um):';
        
                        app.Variable2DropDownLabel.Text = ...
                            'Thickness (um):';
        
                        app.Variable1DropDown.Items = ...
                            string(src.resampled_wavelength_um);
        
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_thickness_um);
        
        
                        % Tdepend has temperature as Variable 3
                        if isTdepend
        
                            app.Variable3DropDownLabel.Text = ...
                                'Temperature (K):';
        
                            app.Variable3DropDown.Items = ...
                                string(src.calculation_temperature_K);
        
                        end
        
        
                    % ------------------------------------------------------
                    % X = THICKNESS
                    % ------------------------------------------------------
        
                    case 'Thickness'
        
                        app.Variable1DropDownLabel.Text = ...
                            'Wavelength (um):';
        
                        app.Variable2DropDownLabel.Text = ...
                            'Incident angle (deg):';
        
                        app.Variable1DropDown.Items = ...
                            string(src.resampled_wavelength_um);
        
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_incident_angles_deg);
        
        
                        % Tdepend has temperature as Variable 3
                        if isTdepend
        
                            app.Variable3DropDownLabel.Text = ...
                                'Temperature (K):';
        
                            app.Variable3DropDown.Items = ...
                                string(src.calculation_temperature_K);
        
                        end
        
        
                    % ------------------------------------------------------
                    % X = TEMPERATURE
                    % ------------------------------------------------------
        
                    case 'Temperature'
        
                        % This X-axis exists only for Tdepend R/T/A.
                        if ~isTdepend
                            return
                        end
        
                        app.Variable1DropDownLabel.Text = ...
                            'Wavelength (um):';
        
                        app.Variable2DropDownLabel.Text = ...
                            'Incident angle (deg):';
        
                        app.Variable3DropDownLabel.Text = ...
                            'Thickness (um):';
        
                        app.Variable1DropDown.Items = ...
                            string(src.resampled_wavelength_um);
        
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_incident_angles_deg);
        
                        app.Variable3DropDown.Items = ...
                            string(src.calculation_thickness_um);
        
                end
        
        
                % ----------------------------------------------------------
                % SET DEFAULT VALUES
                % ----------------------------------------------------------
        
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
        
                app.Variable2DropDown.Value = ...
                    app.Variable2DropDown.Items{1};
        
                app.Variable3DropDown.Value = ...
                    app.Variable3DropDown.Items{1};
        
                updateOpticalPlot(app);
                return
            end
        
        
            % ==============================================================
            % HEMISPHERICAL REFLECTIVITY / TRANSMISSIVITY / ABSORPTIVITY
            % ==============================================================
            
            if ismember(quantity, ...
                    {'Hemispherical Reflectivity', ...
                     'Hemispherical Transmissivity', ...
                     'Hemispherical Absorptivity'})
            
                if isTdepend
            
                    switch xAxis
            
                        case 'Wavelength'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Thickness (um):';
            
                            app.Variable2DropDownLabel.Text = ...
                                'Temperature (K):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_thickness_um);
            
                            app.Variable2DropDown.Items = ...
                                string(src.calculation_temperature_K);
            
            
                        case 'Thickness'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Wavelength (um):';
            
                            app.Variable2DropDownLabel.Text = ...
                                'Temperature (K):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.resampled_wavelength_um);
            
                            app.Variable2DropDown.Items = ...
                                string(src.calculation_temperature_K);
            
            
                        case 'Temperature'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Wavelength (um):';
            
                            app.Variable2DropDownLabel.Text = ...
                                'Thickness (um):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.resampled_wavelength_um);
            
                            app.Variable2DropDown.Items = ...
                                string(src.calculation_thickness_um);
            
            
                        otherwise
            
                            return
            
                    end
            
                else
            
                    switch xAxis
            
                        case 'Wavelength'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Thickness (um):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_thickness_um);
            
            
                        case 'Thickness'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Wavelength (um):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.resampled_wavelength_um);
            
            
                        otherwise
            
                            return
            
                    end
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUES
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
            
                app.Variable2DropDown.Value = ...
                    app.Variable2DropDown.Items{1};
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % DIRECTIONAL EMISSIVITY
            % ==============================================================
            
            if strcmp(quantity, 'Directional Emissivity')
            
                switch xAxis
            
                    case 'Temperature'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Emission angle (deg):';
            
                        app.Variable2DropDownLabel.Text = ...
                            'Thickness (um):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_incident_angles_deg);
            
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_thickness_um);
            
            
                    case 'Emission angle'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Temperature (K):';
            
                        app.Variable2DropDownLabel.Text = ...
                            'Thickness (um):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_temperature_K);
            
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_thickness_um);
            
            
                    case 'Thickness'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Temperature (K):';
            
                        app.Variable2DropDownLabel.Text = ...
                            'Emission angle (deg):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_temperature_K);
            
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_incident_angles_deg);
            
            
                    otherwise
            
                        return
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUES
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
            
                app.Variable2DropDown.Value = ...
                    app.Variable2DropDown.Items{1};
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % HEMISPHERICAL EMISSIVITY
            % ==============================================================
            
            if strcmp(quantity, 'Hemispherical Emissivity')
            
                switch xAxis
            
                    case 'Temperature'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Thickness (um):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_thickness_um);
            
            
                    case 'Thickness'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Temperature (K):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_temperature_K);
            
            
                    otherwise
            
                        return
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUE
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % DIRECTIONAL REFLECTED / TRANSMITTED / ABSORBED FLUX
            % ==============================================================
            
            if ismember(quantity, ...
                    {'Directional Reflected Flux', ...
                     'Directional Transmitted Flux', ...
                     'Directional Absorbed Flux'})
            
                if isTdepend
            
                    switch xAxis
            
                        case 'Incident angle'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Thickness (um):';
            
                            app.Variable2DropDownLabel.Text = ...
                                'Temperature (K):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_thickness_um);
            
                            app.Variable2DropDown.Items = ...
                                string(src.calculation_temperature_K);
            
            
                        case 'Thickness'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Incident angle (deg):';
            
                            app.Variable2DropDownLabel.Text = ...
                                'Temperature (K):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_incident_angles_deg);
            
                            app.Variable2DropDown.Items = ...
                                string(src.calculation_temperature_K);
            
            
                        case 'Temperature'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Incident angle (deg):';
            
                            app.Variable2DropDownLabel.Text = ...
                                'Thickness (um):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_incident_angles_deg);
            
                            app.Variable2DropDown.Items = ...
                                string(src.calculation_thickness_um);
            
            
                        otherwise
            
                            return
            
                    end
            
                else
            
                    switch xAxis
            
                        case 'Incident angle'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Thickness (um):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_thickness_um);
            
            
                        case 'Thickness'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Incident angle (deg):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_incident_angles_deg);
            
            
                        otherwise
            
                            return
            
                    end
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUES
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
            
                app.Variable2DropDown.Value = ...
                    app.Variable2DropDown.Items{1};
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % HEMISPHERICAL REFLECTED / TRANSMITTED / ABSORBED FLUX
            % ==============================================================
            
            if ismember(quantity, ...
                    {'Hemispherical Reflected Flux', ...
                     'Hemispherical Transmitted Flux', ...
                     'Hemispherical Absorbed Flux'})
            
                if isTdepend
            
                    switch xAxis
            
                        case 'Thickness'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Temperature (K):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_temperature_K);
            
            
                        case 'Temperature'
            
                            app.Variable1DropDownLabel.Text = ...
                                'Thickness (um):';
            
                            app.Variable1DropDown.Items = ...
                                string(src.calculation_thickness_um);
            
            
                        otherwise
            
                            return
            
                    end
            
                else
            
                    if ~strcmp(xAxis, 'Thickness')
                        return
                    end
            
                    app.Variable1DropDownLabel.Text = ...
                        'Variable:';
            
                    app.Variable1DropDown.Items = {'-'};
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUE
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % HEMISPHERICAL EMITTED FLUX
            % ==============================================================
            
            if strcmp(quantity, 'Hemispherical Emitted Flux')
            
                switch xAxis
            
                    case 'Temperature'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Thickness (um):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_thickness_um);
            
            
                    case 'Thickness'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Temperature (K):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_temperature_K);
            
            
                    otherwise
            
                        return
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUE
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};

                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % EQUILIBRIUM TEMPERATURE
            % DIRECTIONAL / DIRECTIONAL
            % DIRECTIONAL / HEMISPHERICAL
            % HEMISPHERICAL / DIRECTIONAL
            %
            % DIRECTIONAL / HEMISPHERICAL
            % - CONDUCTIVE FLUX
            % - TEMPERATURE AT THE FRONT SIDE OF THE SLAB
            % - TEMPERATURE AT THE REAR SIDE OF THE SLAB
            % - TEMPERATURE AT THE MIDDLE OF THE SLAB
            % ==============================================================
            
            if ismember(quantity, ...
                    {'Lumped Body Equilibrium Temperature — Directional A / Hemispherical E', ...
                     'Conductive Flux — Directional A / Hemispherical E', ...
                     'Temperature at the front side of the slab — Directional A / Hemispherical E', ...
                     'Temperature at the rear side of the slab — Directional A / Hemispherical E', ...
                     'Temperature at the middle of the slab — Directional A / Hemispherical E'})
            
                switch xAxis
            
                    case 'Incident angle'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Thickness (um):';
            
                        app.Variable2DropDownLabel.Text = ...
                            'Solar concentration multiplier:';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_thickness_um);
            
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_solar_concentration_multipliers);
            
            
                    case 'Thickness'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Incident angle (deg):';
            
                        app.Variable2DropDownLabel.Text = ...
                            'Solar concentration multiplier:';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_incident_angles_deg);
            
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_solar_concentration_multipliers);
            
            
                    case 'Solar concentration multiplier'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Incident angle (deg):';
            
                        app.Variable2DropDownLabel.Text = ...
                            'Thickness (um):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_incident_angles_deg);
            
                        app.Variable2DropDown.Items = ...
                            string(src.calculation_thickness_um);
            
            
                    otherwise
            
                        return
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUES
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
            
                app.Variable2DropDown.Value = ...
                    app.Variable2DropDown.Items{1};
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % EQUILIBRIUM TEMPERATURE
            % HEMISPHERICAL A / HEMISPHERICAL E
            %
            % HEMISPHERICAL / HEMISPHERICAL
            % - CONDUCTIVE FLUX
            % - TEMPERATURE AT THE FRONT SIDE OF THE SLAB
            % - TEMPERATURE AT THE REAR SIDE OF THE SLAB
            % - TEMPERATURE AT THE MIDDLE OF THE SLAB
            % ==============================================================
            
            if ismember(quantity, ...
                    {'Lumped Body Equilibrium Temperature — Hemispherical A / Hemispherical E', ...
                     'Conductive Flux — Hemispherical A / Hemispherical E', ...
                     'Temperature at the front side of the slab — Hemispherical A / Hemispherical E', ...
                     'Temperature at the rear side of the slab — Hemispherical A / Hemispherical E', ...
                     'Temperature at the middle of the slab — Hemispherical A / Hemispherical E'})

            
                switch xAxis
            
                    case 'Thickness'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Solar concentration multiplier:';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_solar_concentration_multipliers);
            
            
                    case 'Solar concentration multiplier'
            
                        app.Variable1DropDownLabel.Text = ...
                            'Thickness (um):';
            
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_thickness_um);
            
            
                    otherwise
            
                        return
            
                end
            
            
                % ----------------------------------------------------------
                % SET DEFAULT VALUE
                % ----------------------------------------------------------
            
                app.Variable1DropDown.Value = ...
                    app.Variable1DropDown.Items{1};
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % THERMAL COVERAGE RATIO
            % ==============================================================
            
            if strcmp(quantity, 'Thermal Coverage Ratio')
            
                if ~strcmp(xAxis, 'Temperature')
                    return
                end
            
                app.Variable1DropDownLabel.Text = ...
                    'Variable:';
            
                app.Variable2DropDownLabel.Text = ...
                    'Variable:';
            
                app.Variable3DropDownLabel.Text = ...
                    'Variable:';
            
                app.Variable1DropDown.Items = {'-'};
                app.Variable2DropDown.Items = {'-'};
                app.Variable3DropDown.Items = {'-'};
            
                app.Variable1DropDown.Value = '-';
                app.Variable2DropDown.Value = '-';
                app.Variable3DropDown.Value = '-';
            
                updateOpticalPlot(app);
                return
            end

            % ==============================================================
            % SOURCE SPREAD (hemispherical emissivity)
            % ==============================================================

            if ismember(quantity, ...
                    {'Hemispherical Emissivity — Source Spread', ...
                     'Hemispherical Emissivity — Relative Source Spread'})

                switch xAxis

                    case 'Temperature'

                        app.Variable1DropDownLabel.Text = 'Thickness (um):';
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_thickness_um);

                    case 'Thickness'

                        app.Variable1DropDownLabel.Text = 'Temperature (K):';
                        app.Variable1DropDown.Items = ...
                            string(src.calculation_temperature_K);

                    otherwise
                        return

                end

                app.Variable1DropDown.Value = app.Variable1DropDown.Items{1};

                updateOpticalPlot(app);
                return
            end
        
        
            updateOpticalPlot(app);
            
        end

        % Value changed function: Variable1DropDown
        function Variable1DropDownValueChanged(app, event)
            updateOpticalPlot(app);
        end

        % Value changed function: Variable2DropDown
        function Variable2DropDownValueChanged(app, event)
            updateOpticalPlot(app);
        end

        % Value changed function: Variable3DropDown
        function Variable3DropDownValueChanged(app, event)
            updateOpticalPlot(app);
        end

        % Value changed function: OpticalQuantityDropDown
        function OpticalQuantityDropDownValueChanged(app, event)

    quantity = app.OpticalQuantityDropDown.Value;

    % ==============================================================
    % RE-INITIALIZE VARIABLE 3
    % ==============================================================

    app.Variable3DropDownLabel.Text = 'Variable:';
    app.Variable3DropDown.Items = {'-'};
    app.Variable3DropDown.Value = '-';


    % ==============================================================
    % SELECT ALLOWED X-AXES
    % ==============================================================

    switch quantity

        % ----------------------------------------------------------
        % Refractive index / extinction coefficient
        % ----------------------------------------------------------
        case {'Refractive index (n)', ...
              'Extinction coefficient (k)'}

            app.OpticalXAxisDropDown.Items = {'Wavelength'};
            app.OpticalXAxisDropDown.Value = 'Wavelength';

        % ----------------------------------------------------------
        % Refractive index / extinction coefficient
        % ----------------------------------------------------------
        case 'Thermal Conductivity'

            app.OpticalXAxisDropDown.Items = {'Temperature'};
            app.OpticalXAxisDropDown.Value = 'Temperature';


        % ----------------------------------------------------------
        % Directional Reflectivity
        % ----------------------------------------------------------
        case 'Directional Reflectivity'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Incident angle', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Incident angle', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Wavelength';


        % ----------------------------------------------------------
        % Directional Transmissivity
        % ----------------------------------------------------------
        case 'Directional Transmissivity'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Incident angle', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Incident angle', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Wavelength';


        % ----------------------------------------------------------
        % Directional Absorptivity
        % ----------------------------------------------------------
        case 'Directional Absorptivity'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Incident angle', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Incident angle', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Wavelength';


        % ----------------------------------------------------------
        % Directional Emissivity
        % ----------------------------------------------------------
        case 'Directional Emissivity'

            app.OpticalXAxisDropDown.Items = ...
                {'Temperature', ...
                 'Emission angle', ...
                 'Thickness'};

            app.OpticalXAxisDropDown.Value = 'Temperature';


        % ----------------------------------------------------------
        % Hemispherical Reflectivity
        % ----------------------------------------------------------
        case 'Hemispherical Reflectivity'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Wavelength';


        % ----------------------------------------------------------
        % Hemispherical Transmissivity
        % ----------------------------------------------------------
        case 'Hemispherical Transmissivity'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Wavelength';


        % ----------------------------------------------------------
        % Hemispherical Absorptivity
        % ----------------------------------------------------------
        case 'Hemispherical Absorptivity'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Wavelength', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Wavelength';


        % ----------------------------------------------------------
        % Hemispherical Emissivity
        % ----------------------------------------------------------
        case 'Hemispherical Emissivity'

            app.OpticalXAxisDropDown.Items = ...
                {'Temperature', 'Thickness'};

            app.OpticalXAxisDropDown.Value = 'Temperature';


        % ----------------------------------------------------------
        % Directional Reflected Flux
        % ----------------------------------------------------------
        case 'Directional Reflected Flux'


            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Incident angle', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Incident angle', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Incident angle';


        % ----------------------------------------------------------
        % Directional Transmitted Flux
        % ----------------------------------------------------------
        case 'Directional Transmitted Flux'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Incident angle', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Incident angle', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Incident angle';


        % ----------------------------------------------------------
        % Directional Absorbed Flux
        % ----------------------------------------------------------
        case 'Directional Absorbed Flux'

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Incident angle', ...
                     'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Incident angle', ...
                     'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Incident angle';

        % ----------------------------------------------------------
        % Hemispherical reflected/transmitted/absorbed flux
        % ----------------------------------------------------------
        case {'Hemispherical Reflected Flux', ...
              'Hemispherical Transmitted Flux', ...
              'Hemispherical Absorbed Flux'}

            app.OpticalXAxisDropDown.Items = ...
                {'Thickness'};

            if endsWith(app.AuthorDropDown.Value, '-Tdepend')

                app.OpticalXAxisDropDown.Items = ...
                    {'Thickness', ...
                     'Temperature'};

            else

                app.OpticalXAxisDropDown.Items = ...
                    {'Thickness'};

            end

            app.OpticalXAxisDropDown.Value = 'Thickness';


        % ----------------------------------------------------------
        % Hemispherical emitted flux
        % ----------------------------------------------------------
        case 'Hemispherical Emitted Flux'

            app.OpticalXAxisDropDown.Items = ...
                {'Temperature', 'Thickness'};

            app.OpticalXAxisDropDown.Value = 'Temperature';


        % ----------------------------------------------------------
        % Lumped Body Equilibrium temperatures & Conductive fluxes & Slab
        % temperatures (dir-hemi)
        % ----------------------------------------------------------
        case {'Lumped Body Equilibrium Temperature — Directional A / Hemispherical E', ...
              'Conductive Flux — Directional A / Hemispherical E', ...
              'Temperature at the front side of the slab — Directional A / Hemispherical E', ...
              'Temperature at the rear side of the slab — Directional A / Hemispherical E', ...
              'Temperature at the middle of the slab — Directional A / Hemispherical E'}

            app.OpticalXAxisDropDown.Items = ...
                {'Incident angle', ...
                 'Thickness', ...
                 'Solar concentration multiplier'};

            app.OpticalXAxisDropDown.Value = 'Incident angle';


        % -------------------------------------------------------------------------------------------------------
        % Equilibrium temperature & Conductive fluxes & Fourier temperatures — hemispherical A / hemispherical E
        % -------------------------------------------------------------------------------------------------------
        case {'Lumped Body Equilibrium Temperature — Hemispherical A / Hemispherical E', ...
              'Conductive Flux — Hemispherical A / Hemispherical E', ...
              'Temperature at the front side of the slab — Hemispherical A / Hemispherical E', ...
              'Temperature at the rear side of the slab — Hemispherical A / Hemispherical E', ...
              'Temperature at the middle of the slab — Hemispherical A / Hemispherical E'}

            app.OpticalXAxisDropDown.Items = ...
                {'Thickness', ...
                 'Solar concentration multiplier'};

            app.OpticalXAxisDropDown.Value = 'Thickness';


        % ----------------------------------------------------------
        % Thermal coverage ratio
        % ----------------------------------------------------------
        case 'Thermal Coverage Ratio'

            app.OpticalXAxisDropDown.Items = {'Temperature'};
            app.OpticalXAxisDropDown.Value = 'Temperature';

        % ----------------------------------------------------------
        % Source spread of hemispherical emissivity
        % ----------------------------------------------------------
        case {'Hemispherical Emissivity — Source Spread', ...
              'Hemispherical Emissivity — Relative Source Spread'}

            app.OpticalXAxisDropDown.Items = {'Temperature', 'Thickness'};
            app.OpticalXAxisDropDown.Value = 'Temperature';


        otherwise

            return

    end


    % ==============================================================
    % UPDATE VARIABLE DROPDOWNS
    % ==============================================================

    OpticalXAxisDropDownValueChanged(app, []);

        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpticalAxes = uiaxes(app.UIFigure);
            xlabel(app.OpticalAxes, 'X')
            ylabel(app.OpticalAxes, 'Y')
            zlabel(app.OpticalAxes, 'Z')
            app.MaterialLabel = uilabel(app.UIFigure);
            app.MaterialLabel.Text = 'Material:';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Select material'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Select material';
            app.AuthorLabel = uilabel(app.UIFigure);
            app.AuthorLabel.Text = 'Author:';
            app.AuthorDropDown = uidropdown(app.UIFigure);
            app.AuthorDropDown.Items = {'Select author'};
            app.AuthorDropDown.ValueChangedFcn = createCallbackFcn(app, @AuthorDropDownValueChanged, true);
            app.AuthorDropDown.Value = 'Select author';
            app.OpticalQuantityDropDown = uidropdown(app.UIFigure);
            app.OpticalQuantityDropDown.Items = {'Refractive index (n)', 'Extinction coefficient (k)', 'Directional Reflectivity', 'Directional Transmissivity', 'Directional Absorptivity', 'Directional Emissivity', 'Hemispherical Reflectivity', 'Hemispherical Transmissivity', 'Hemispherical Absorptivity', 'Hemispherical Emissivity', 'Directional Reflected Flux', 'Directional Transmitted Flux', 'Directional Absorbed Flux', 'Hemispherical Reflected Flux', 'Hemispherical Transmitted Flux', 'Hemispherical Absorbed Flux', 'Hemispherical Emitted Flux', 'Lumped Body Equilibrium Temperature — Directional A / Hemispherical E', 'Lumped Body Equilibrium Temperature — Hemispherical A / Hemispherical E', 'Thermal Conductivity', 'Conductive Flux — Directional A / Hemispherical E', 'Conductive Flux — Hemispherical A / Hemispherical E', 'Temperature at the front side of the slab — Directional A / Hemispherical E', 'Temperature at the rear side of the slab — Directional A / Hemispherical E', 'Temperature at the middle of the slab — Directional A / Hemispherical E', 'Temperature at the front side of the slab — Hemispherical A / Hemispherical E', 'Temperature at the rear side of the slab — Hemispherical A / Hemispherical E', 'Temperature at the middle of the slab — Hemispherical A / Hemispherical E', 'Thermal Coverage Ratio', 'Hemispherical Emissivity — Source Spread', 'Hemispherical Emissivity — Relative Source Spread'};
            app.OpticalQuantityDropDown.ValueChangedFcn = createCallbackFcn(app, @OpticalQuantityDropDownValueChanged, true);
            app.OpticalQuantityDropDown.Placeholder = 'Select property to plot';
            app.OpticalQuantityDropDown.Value = 'Refractive index (n)';
            app.QuantitytoPlotLabel = uilabel(app.UIFigure);
            app.QuantitytoPlotLabel.Text = 'Quantity to Plot:';
            app.ReferenceLabel = uilabel(app.UIFigure);
            app.ReferenceLabel.Text = 'Reference:';
            app.UppertemperaturelimitKLabel = uilabel(app.UIFigure);
            app.UppertemperaturelimitKLabel.Text = 'Upper temperature limit (K):';
            app.SolarCoverageRatioLabel = uilabel(app.UIFigure);
            app.SolarCoverageRatioLabel.Text = 'Solar coverage ratio:';
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X axis:';
            app.OpticalXAxisDropDown = uidropdown(app.UIFigure);
            app.OpticalXAxisDropDown.Items = {'Select  X-axis'};
            app.OpticalXAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @OpticalXAxisDropDownValueChanged, true);
            app.OpticalXAxisDropDown.Value = 'Select  X-axis';
            app.Variable1DropDownLabel = uilabel(app.UIFigure);
            app.Variable1DropDownLabel.Text = 'Variable1:';
            app.Variable1DropDown = uidropdown(app.UIFigure);
            app.Variable1DropDown.Items = {'Select value'};
            app.Variable1DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable1DropDownValueChanged, true);
            app.Variable1DropDown.Value = 'Select value';
            app.Variable2DropDownLabel = uilabel(app.UIFigure);
            app.Variable2DropDownLabel.Text = 'Variable2:';
            app.Variable2DropDown = uidropdown(app.UIFigure);
            app.Variable2DropDown.Items = {'Select value'};
            app.Variable2DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable2DropDownValueChanged, true);
            app.Variable2DropDown.Value = 'Select value';
            app.Variable3DropDownLabel = uilabel(app.UIFigure);
            app.Variable3DropDownLabel.Text = 'Variable3:';
            app.Variable3DropDown = uidropdown(app.UIFigure);
            app.Variable3DropDown.Items = {'Select value'};
            app.Variable3DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable3DropDownValueChanged, true);
            app.Variable3DropDown.Value = 'Select value';
            app.SpaderOpticsLabel = uilabel(app.UIFigure);
            app.SpaderOpticsLabel.Text = 'SpaderOptics';
            app.MetadataLabel = uilabel(app.UIFigure);
            app.MetadataLabel.Text = 'Metadata';
            app.ReferenceButton = uibutton(app.UIFigure, 'push');
            app.ReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceButtonPushed, true);
            app.ReferenceButton.Text = '[View Reference]';
            app.CreatePlotLabel = uilabel(app.UIFigure);
            app.CreatePlotLabel.Text = 'Create Plot';
            app.ControltherestvariablesLabel = uilabel(app.UIFigure);
            app.ControltherestvariablesLabel.Text = 'Control the rest variables:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpaderOpticsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end